classdef AdjustMAs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MortgageCalculatorUIFigure   matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        WaypointValueEditField       matlab.ui.control.NumericEditField
        WaypointValueEditFieldLabel  matlab.ui.control.Label
        ErrorPanel                   matlab.ui.container.Panel
        ErrorTxt                     matlab.ui.control.Label
        ChangeWaypointsButtonGroup   matlab.ui.container.ButtonGroup
        Pt8Label_2                   matlab.ui.control.Label
        Pt7Label                     matlab.ui.control.Label
        ZButton_2                    matlab.ui.control.RadioButton
        YButton_2                    matlab.ui.control.RadioButton
        XButton_2                    matlab.ui.control.RadioButton
        ZButton                      matlab.ui.control.RadioButton
        YButton                      matlab.ui.control.RadioButton
        XButton                      matlab.ui.control.RadioButton
        RotXEditField                matlab.ui.control.NumericEditField
        RotZEditField                matlab.ui.control.NumericEditField
        RotYEditField                matlab.ui.control.NumericEditField
        LoadDataButton               matlab.ui.control.Button
        SaveModelButton              matlab.ui.control.Button
        AnimalNumberDropDown         matlab.ui.control.DropDown
        AnimalNumberDropDownLabel    matlab.ui.control.Label
        YEditField_2Label            matlab.ui.control.Label
        XEditField_2Label            matlab.ui.control.Label
        XEditFieldLabel_3            matlab.ui.control.Label
        RotationLabel                matlab.ui.control.Label
        XEditFieldLabel_2            matlab.ui.control.Label
        TranslationLabel             matlab.ui.control.Label
        TransZEditField              matlab.ui.control.NumericEditField
        TransYEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        TransXEditField              matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        RadiusEditField              matlab.ui.control.NumericEditField
        RadiusEditFieldLabel         matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        PrincipalInterestUIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data % Description
        
        model % filenames of the models
        modelNums % aniNums of corresponding to the model files
        AniData % Morphology Data 
        wayButton % Value depicting which radio button is selected to adjust waypoint values
        guineaModelFolder %folder where models are stored
        models %names of models stored int he guineaModelFolder
    end

    
    methods (Access = private)
        
        function  CalcMomentArms(app)
            wrap = app.model.getBodySet().get('tibia').getWrapObjectSet().get('ANKLE_4');
            prop = wrap.getPropertyByName('translation');
            rotate = wrap.getPropertyByName('xyz_body_rotation');
            for i=1:3
                org.opensim.modeling.PropertyHelper.setValueVec3(app.AniData.translation(i),prop, i-1);
                org.opensim.modeling.PropertyHelper.setValueVec3(app.AniData.rotation(i),rotate, i-1);
            end
            org.opensim.modeling.PropertyHelper.setValueDouble(0.02,wrap.getPropertyByName('length'));
            %PropertyHelper.setValueDouble(0.02,wrap.getPropertyByName('length'));
            %PropertyHelper.setValueDouble(app.AniData.radius,wrap.getPropertyByName('radius'));
            org.opensim.modeling.PropertyHelper.setValueDouble(app.AniData.radius,wrap.getPropertyByName('radius'));
%             wc = WrapCylinder.safeDownCast(wrap);
%             wc.set_radius(app.AniData.radius);
            wrap.upd_translation();
            LgMa = ones(length(app.AniData.newy),1)';
            LG = app.model.getMuscles().get('LG');
            state = app.model.initSystem();
            count=1;
             ankleFlexCoord = app.model.updCoordinateSet().get('ankle_flexion');
            for angle = linspace(app.AniData.angleMin,app.AniData.angleMax,length(app.AniData.newy))
                ankleFlexCoord.setValue(state,angle);
                LgMa(count)=LG.computeMomentArm(state,ankleFlexCoord);
                count=count+1;
            end
            app.AniData.LgMa = LgMa;
            app.AniData.diffMa =  (sum(abs((LgMa-app.AniData.newy))))/length(LgMa);  %use least squares to calcualte the fit of the new moment arm to the data - only across the area we have data
            app.ErrorTxt.Text = num2str(app.AniData.diffMa);
            if app.AniData.diffMa>0.0001
                app.ErrorPanel.BackgroundColor = [0.87, 0.62,0.62];
            else
                app.ErrorPanel.BackgroundColor = [0.69, 0.87,0.62];
            end
        end
        
        function PlotMomentArms(app)
            plot(app.PrincipalInterestUIAxes,app.AniData.newxs,app.AniData.newy,'Color','g')
            hold(app.PrincipalInterestUIAxes,'on');
            plot(app.PrincipalInterestUIAxes,app.AniData.newxs,app.AniData.LgMa,'Color','r','LineStyle','--')
            app.ErrorTxt.Text = num2str(app.AniData.diffMa);
            hold(app.PrincipalInterestUIAxes, 'off')
            legend(app.PrincipalInterestUIAxes,'Experimental','Model')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MortgageCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function MonthlyPaymentButtonPushed(app, event)
                       
            % Calculate the monthly payment
            amount = app.RadiusEditField.Value ;
            rate = app.TransXEditField.Value/12/100 ;
            nper = 12*app.TransYEditField.Value ;
            payment = (amount*rate)/(1-(1+rate)^-nper);
            app.TransZEditField.Value = payment;
            
            % pre allocating and initializing variables 
            interest = zeros(1,nper);
            principal = zeros(1,nper);
            balance = zeros (1,nper);
            
            balance(1) = amount;
            
            % Calculate the principal and interest over time
            for i = 1:nper
                interest(i)  = balance(i)*rate ;
                principal(i) = payment - interest(i) ;
                balance(i+1) = balance(i) - principal(i) ;
            end
            
            % Plot the principal and interest
            plot(app.PrincipalInterestUIAxes, (1:nper)', principal, ...
                (1:nper)', interest) ;
            legend(app.PrincipalInterestUIAxes,{'Principal','Interest'},'Location','Best')
            xlim(app.PrincipalInterestUIAxes,[0 nper]) ; 
            
        end

        % Value changed function: TransXEditField
        function TransXEditFieldValueChanged(app, event)
            app.AniData.translation(1) = app.TransXEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: TransYEditField
        function TransYEditFieldValueChanged(app, event)
            app.AniData.translation(2) = app.TransYEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: TransZEditField
        function TransZEditFieldValueChanged(app, event)
            app.AniData.translation(3) = app.TransZEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: AnimalNumberDropDown
        function ChooseAniNum(app, event)
            value = app.AnimalNumberDropDown.Value;
            app.AniData = app.Data([app.Data.AniNum] == str2double(value));
            valueIdx = find(strcmp([app.modelNums{:}],value));
            app.AniData.inputFileName = fullfile(app.models(valueIdx).folder,app.models(valueIdx).name);
            app.AniData.outputFileName = fullfile(app.guineaModelFolder,['GuineaB',value,'_ma.osim']);
            app.PrincipalInterestUIAxes.Title.String = app.AniData.AniNumStr;
            app.AniData.angleMin = min(app.AniData.maAngles);
            app.AniData.angleMax =  max(app.AniData.maAngles);
            spline1 = spap2(1,4,app.AniData.maAngles,app.AniData.ma);
            app.AniData.newxs = app.AniData.maAngles;
            app.AniData.newy = -fnval(spline1,app.AniData.newxs);
            if exist(app.AniData.inputFileName,'file')
                 app.model.disownAllComponents();
                app.model = org.opensim.modeling.Model(app.AniData.inputFileName);
            end
            wrap = app.model.getBodySet().get('tibia').getWrapObjectSet().get('ANKLE_4');
            
            app.AniData.translation = osimVec3ToArray(wrap.get_translation());
            app.AniData.radius =org.opensim.modeling.PropertyHelper.getValueDouble(wrap.getPropertyByName('radius'));
            app.AniData.rotation =osimVec3ToArray(wrap.get_xyz_body_rotation());
            app.TransXEditField.Value=app.AniData.translation(1);
            app.TransYEditField.Value=app.AniData.translation(2);
            app.TransZEditField.Value=app.AniData.translation(3);
            
            app.RotXEditField.Value=app.AniData.rotation(1);
            app.RotYEditField.Value=app.AniData.rotation(2);
            app.RotZEditField.Value=app.AniData.rotation(3);
            app.RadiusEditField.Value=app.AniData.radius;
            app.wayButton = 71;
            app.AniData.locP7 = app.model.getMuscles().get('LG').get_GeometryPath().getPathPointSet().get(6).getPropertyByName('location');
            app.AniData.locP8 = app.model.getMuscles().get('LG').get_GeometryPath().getPathPointSet().get(7).getPropertyByName('location');
            app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP7,0);
            app.XButton.Value = 1;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Button pushed function: LoadDataButton
        function LoadDataCallback(app, event)
            import org.opensim.modeling.*
            app.guineaModelFolder = uigetdir('Choose the folder with the guinea models');
            app.models = dir(fullfile(app.guineaModelFolder,'*.osim'));
            cd(fileparts(app.guineaModelFolder))
            d2=load('MorphData25 Nov 2020.mat');
  
            app.Data = d2.d2([d2.d2.group]~='E');
            for i=1:length(app.Data)
                app.Data(i).AniNumStr = num2str(app.Data(i).AniNum);
            end
            for i=1:length(app.models)
              app.modelNums{i} =  regexp(app.models(i).name,'\d*','Match');
            end
            app.AnimalNumberDropDown.Items = {app.Data.AniNumStr};
            app.model =Model(fullfile(app.guineaModelFolder,app.models(1).name));
            ChooseAniNum(app, event)
        end

        % Callback function
        function RotationYEdit(app, event)
            app.AniData.rotation(2) = app.RotYEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: RotXEditField
        function RotXEdit(app, event)
            app.AniData.rotation(1)  = app.RotXEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: RotYEditField
        function RotYEdit(app, event)
            app.AniData.rotation(2)  = app.RotYEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: RotZEditField
        function RotZEdit(app, event)
            app.AniData.rotation(3)  = app.RotZEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Value changed function: RadiusEditField
        function RadiusEdit(app, event)
            app.AniData.radius = app.RadiusEditField.Value;
            CalcMomentArms(app)
            PlotMomentArms(app)
        end

        % Selection changed function: ChangeWaypointsButtonGroup
        function WaypointButtons(app, event)
            selectedButton = app.ChangeWaypointsButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '7X'
                    app.wayButton = 71;
                     app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP7,0);
                case '7Y'
                     app.wayButton = 72;
                      app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP7,1);
                case '7Z'
                     app.wayButton = 73;
                      app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP7,2);
                case '8X'
                     app.wayButton = 81;
                      app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP8,0);
                case '8Y'
                     app.wayButton = 82;
                      app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP8,1);
                case '8Z'
                     app.wayButton = 83;
                      app.WaypointValueEditField.Value= org.opensim.modeling.PropertyHelper.getValueVec3(app.AniData.locP8,2);
            end
        
        end

        % Value changed function: WaypointValueEditField
        function waypointValueEditCallback(app, event)
          selectedButton = app.ChangeWaypointsButtonGroup.SelectedObject;
           value = app.WaypointValueEditField.Value;
          switch selectedButton.Text
              case '7X'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP7,0) ;
              case '7Y'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP7,1);
              case '7Z'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP7,2);
              case '8X'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP8,0);
              case '8Y'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP8,1);
              case '8Z'
                  org.opensim.modeling.PropertyHelper.setValueVec3(value,app.AniData.locP8,2);
          end
          app.model.initSystem();
          CalcMomentArms(app)
          PlotMomentArms(app)
        end

        % Button pushed function: SaveModelButton
        function SaveModel(app, event)
            %adjust the rest of the muscle and tendon data to match experimental
            % first find the correspoinding row in the data file
            idx = find([app.Data.AniNum]==app.AniData.AniNum);
            LG = app.model.getMuscles().get('LG');
            LG.setMaxIsometricForce(app.Data(idx).fMaxLg)
            LG.setPennationAngleAtOptimalFiberLength(app.Data(idx).optPenAngLg);
            LG.setOptimalFiberLength(app.Data(idx).LgAvOfl);
            LG.setTendonSlackLength(app.Data(idx).tendonSlackLengthLGmm/1000);
            
            MG = app.model.getMuscles().get('MG');
            MG.setMaxIsometricForce(app.Data(idx).fMaxMg)
            MG.setPennationAngleAtOptimalFiberLength(app.Data(idx).optPenAngMg);
            MG.setOptimalFiberLength(app.Data(idx).MgAvOfl);
            MG.setTendonSlackLength(app.Data(idx).tendonSlackLengthMGmm/1000);
         
        app.model.print(app.AniData.outputFileName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MortgageCalculatorUIFigure = uifigure('Visible', 'off');
            app.MortgageCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.MortgageCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MortgageCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RadiusEditFieldLabel = uilabel(app.LeftPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RadiusEditField.ValueDisplayFormat = '%2.5f';
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEdit, true);
            app.RadiusEditField.Value = 300000;
            app.XEditFieldLabel = uilabel(app.LeftPanel);
            app.XEditFieldLabel.Text = 'X';
            app.TransXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TransXEditField.ValueDisplayFormat = '%7.5f';
            app.TransXEditField.ValueChangedFcn = createCallbackFcn(app, @TransXEditFieldValueChanged, true);
            app.TransXEditField.Value = 4;
            app.YEditFieldLabel = uilabel(app.LeftPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.TransYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TransYEditField.ValueDisplayFormat = '%7.5f';
            app.TransYEditField.ValueChangedFcn = createCallbackFcn(app, @TransYEditFieldValueChanged, true);
            app.TransYEditField.Value = 30;
            app.TransZEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TransZEditField.ValueDisplayFormat = '%7.5f';
            app.TransZEditField.ValueChangedFcn = createCallbackFcn(app, @TransZEditFieldValueChanged, true);
            app.TranslationLabel = uilabel(app.LeftPanel);
            app.TranslationLabel.Text = 'Translation';
            app.XEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.XEditFieldLabel_2.Text = {'Z'; ''};
            app.RotationLabel = uilabel(app.LeftPanel);
            app.RotationLabel.Text = 'Rotation';
            app.XEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.XEditFieldLabel_3.Text = {'Z'; ''};
            app.XEditField_2Label = uilabel(app.LeftPanel);
            app.XEditField_2Label.Text = 'X';
            app.YEditField_2Label = uilabel(app.LeftPanel);
            app.YEditField_2Label.Text = 'Y';
            app.AnimalNumberDropDownLabel = uilabel(app.LeftPanel);
            app.AnimalNumberDropDownLabel.Text = 'Animal Number';
            app.AnimalNumberDropDown = uidropdown(app.LeftPanel);
            app.AnimalNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseAniNum, true);
            app.SaveModelButton = uibutton(app.LeftPanel, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModel, true);
            app.SaveModelButton.Text = 'Save Model';
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataCallback, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RotYEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RotYEditField.ValueDisplayFormat = '%7.5f';
            app.RotYEditField.ValueChangedFcn = createCallbackFcn(app, @RotYEdit, true);
            app.RotYEditField.Value = 30;
            app.RotZEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RotZEditField.ValueDisplayFormat = '%7.5f';
            app.RotZEditField.ValueChangedFcn = createCallbackFcn(app, @RotZEdit, true);
            app.RotXEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RotXEditField.ValueDisplayFormat = '%7.5f';
            app.RotXEditField.ValueChangedFcn = createCallbackFcn(app, @RotXEdit, true);
            app.RotXEditField.Value = 4;
            app.ChangeWaypointsButtonGroup = uibuttongroup(app.LeftPanel);
            app.ChangeWaypointsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaypointButtons, true);
            app.ChangeWaypointsButtonGroup.Title = 'Change Waypoints';
            app.XButton = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.XButton.Text = '7X';
            app.YButton = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.YButton.Text = '7Y';
            app.ZButton = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.ZButton.Text = '7Z';
            app.ZButton.Value = true;
            app.XButton_2 = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.XButton_2.Text = '8X';
            app.YButton_2 = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.YButton_2.Text = '8Y';
            app.ZButton_2 = uiradiobutton(app.ChangeWaypointsButtonGroup);
            app.ZButton_2.Text = '8Z';
            app.Pt7Label = uilabel(app.ChangeWaypointsButtonGroup);
            app.Pt7Label.Text = 'Pt 7';
            app.Pt8Label_2 = uilabel(app.ChangeWaypointsButtonGroup);
            app.Pt8Label_2.Text = {'Pt 8'; ''};
            app.ErrorPanel = uipanel(app.LeftPanel);
            app.ErrorPanel.Title = 'Error';
            app.ErrorTxt = uilabel(app.ErrorPanel);
            app.ErrorTxt.Text = 'None Yet';
            app.WaypointValueEditFieldLabel = uilabel(app.LeftPanel);
            app.WaypointValueEditFieldLabel.Text = 'Waypoint Value';
            app.WaypointValueEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WaypointValueEditField.ValueChangedFcn = createCallbackFcn(app, @waypointValueEditCallback, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.PrincipalInterestUIAxes = uiaxes(app.RightPanel);
            title(app.PrincipalInterestUIAxes, 'Animal Number')
            xlabel(app.PrincipalInterestUIAxes, 'Angle')
            ylabel(app.PrincipalInterestUIAxes, 'Moment Arm (mm)')
            % Show the figure after all components are created
            app.MortgageCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustMAs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MortgageCalculatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MortgageCalculatorUIFigure)
        end
    end
end